classdef Clueless < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        clFigure         matlab.ui.Figure
        Toolbar          matlab.ui.container.Toolbar
        LightDark        matlab.ui.container.toolbar.ToggleTool
        About            matlab.ui.container.toolbar.PushTool
        GclBoard         matlab.ui.container.GridLayout
        clBoard          matlab.ui.container.Panel
        GclLayout        matlab.ui.container.GridLayout
        MovesListPanel   matlab.ui.container.Panel
        Gmoves           matlab.ui.container.GridLayout
        NextMove         matlab.ui.control.Button
        AskButton        matlab.ui.control.Button
        ReplyDD          matlab.ui.control.DropDown
        AskC3            matlab.ui.control.Spinner
        AskC2            matlab.ui.control.Spinner
        AskC1            matlab.ui.control.Spinner
        AskPlayer        matlab.ui.control.DropDown
        Moves            matlab.ui.control.Table
        CardsDistPanel   matlab.ui.container.Panel
        Gcards           matlab.ui.container.GridLayout
        Check            matlab.ui.control.Button
        EnvelopeC3       matlab.ui.control.Spinner
        EnvelopeC2       matlab.ui.control.Spinner
        EnvelopeC1       matlab.ui.control.Spinner
        EnvelopeCsLabel  matlab.ui.control.Label
        CommonC3         matlab.ui.control.EditField
        CommonC2         matlab.ui.control.EditField
        CommonC1         matlab.ui.control.EditField
        CommonCsLabel    matlab.ui.control.Label
        PlayersC3        matlab.ui.control.EditField
        PlayersC2        matlab.ui.control.EditField
        PlayersC1        matlab.ui.control.EditField
        PlayersCsLabel   matlab.ui.control.Label
        GameSetupPanel   matlab.ui.container.Panel
        Gsetup           matlab.ui.container.GridLayout
        ModeSwitch       matlab.ui.control.Switch
        LetsPlay         matlab.ui.control.Button
        NumYou           matlab.ui.control.Spinner
        NumYouLabel      matlab.ui.control.Label
        NumPlayers       matlab.ui.control.Spinner
        NumPlayersLabel  matlab.ui.control.Label
        NumCards         matlab.ui.control.Spinner
        NumCardsLabel    matlab.ui.control.Label
        NumCats          matlab.ui.control.Spinner
        LoadGame         matlab.ui.control.DropDown
        NumCatsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        %% Obvious Variables
        games %Pre-loaded games to solve
        levels %Game levels to play
        plyrCards %(no. of players)×3 cell array
        yourCards %1×3 cell array
        commonCards %1×3 cell array
        moves %(no. of moves)×(no. of cat.+2)×(no. of players) double array
        movesTable %moves translated to the table version
        reply %a dictionary translating the last column of moves to movesTable (defined in startup)
        solV %current game's solution (NOT to be confused with solE!)
        %% App Colours & Styles
        catColor=[0.18,0.75,0.94;0.23,0.67,0.20;0.82,0.02,0.55] %Add more colours for more categories!
        catColorHex=["#2EBFF0","#3BAB33","#D1058C"] %Add here too!
        catStyle=@(col)uistyle("BackgroundColor",col,FontColor='w')
        plyColor=[0.75 0.36 0.98]
        plyStyle=uistyle("BackgroundColor",[0.75 0.36 0.98])
        %% App Info
        majVer=1 %Major version
        minVer=0 %Minor version
        prog=22 %χ %Never change this!
        relz="1" %Release %Always string (keep space between number & letter)! 
        relzDate=datetime(2026,2,14) %Release date
        %% .NET stuff
        speaker=[]
        %% Files & Folders
        %mfilepath=fileparts(mfilename('fullpath'))
        %curpath=pwd
        %clmat=[]
    end

    properties (Dependent, Access = private)
        num3
        numC
        numP
        numY
        solE
    end

    methods %for Dependent properties

        function t = get.num3(app)
            t=app.NumCats.Value;
            %Make sure 2 cards are available to each player!
            app.NumCards.Limits(1)=ceil(1+(2*app.NumPlayers.Value/t));
        end

        function n = get.numC(app)
            n=app.NumCards.Value;
        end

        function m = get.numP(app)
            m=app.NumPlayers.Value;
            %Make sure 2 cards are available to each player!
            app.NumCards.Limits(1)=ceil(1+(2*m/app.NumCats.Value));
            %NumPlayers sets the upper limit of "your" player #
            app.NumYou.Limits(2)=m;
        end
        
        function y = get.numY(app)
            y=app.NumYou.Value;
        end

        function s = get.solE(app)
            s=[app.EnvelopeC1.Value app.EnvelopeC2.Value app.EnvelopeC3.Value];
        end

    end
    
    methods (Access = private)
        
        function trychk(app, event, funcall)
            try
                funcall(app, event);
            catch err
                if ~isempty(app.speaker)
                    SpeakAsync(app.speaker,'Well, that was unexpected!');
                end
                detail=struct2cell(err.stack)';
                detail=strcat(detail(:,2),' in Line-',cellstr(cellfun(@num2str,detail(:,3),'UniformOutput',0)));
                uialert(app.clFigure,[[err.identifier,': '];err.message;flipud(detail)],'Something Went Wrong!😱','Icon',"error");
            end
        end

        function gameGenerator(app)
            c3=app.num3;m=app.numP;n=app.numC;
            %Generate cards
            cards=(1:c3)'*10+(1:n);
            %Pick cards for envelope (Solution)
            app.solV=randi(n,[1 c3]);
            %Shuffle the cards for distribution
            cards=cards(~ismember(cards,(1:c3)*10+app.solV));
            nC2D=c3*(n-1); %No. of cards to distribute
            cards=cards(randperm(nC2D));
            nCC=mod(nC2D,m); %No. of common cards
            %Players get the cards
            for j=1:m
                for k=j:m:nC2D-nCC
                    app.plyrCards{j,floor(cards(k)/10)}(end+1)=mod(cards(k),10);
                end
            end
            app.yourCards=app.plyrCards(app.numY,:);
            %Remaining common cards
            for j=0:nCC-1
                app.commonCards{floor(cards(end-j)/10)}(end+1)=mod(cards(end-j),10);
            end
            %Load the game
            gameLoader(app);
        end

        function gameLoader(app)
            %(Re)Set the card boxes for all the players, the common cards & the envelope
            set(app.PlayersC1(:),Value="{?}");
            set(app.PlayersC2(:),Value="{?}");
            set(app.PlayersC3(:),Value="{?}");
            app.PlayersC1(app.numY).Value="["+strjoin(string(app.yourCards{1}),",")+"]";
            app.PlayersC2(app.numY).Value="["+strjoin(string(app.yourCards{2}),",")+"]";
            app.PlayersC3(app.numY).Value="["+strjoin(string(app.yourCards{3}),",")+"]";
            app.CommonC1.Value="["+strjoin(string(app.commonCards{1}),",")+"]";
            app.CommonC2.Value="["+strjoin(string(app.commonCards{2}),",")+"]";
            app.CommonC3.Value="["+strjoin(string(app.commonCards{3}),",")+"]";
            app.EnvelopeC1.Value=0;
            app.EnvelopeC2.Value=0;
            app.EnvelopeC3.Value=0;
            %Prepare 'your' moves table to be used to fill the moves uitable: 
            if ~isempty(app.moves)
                player="👤"+(mod(0:height(app.moves)-1,app.numP)'+1);
                asked="👤"+app.moves(:,1,app.numY);
                app.movesTable=table(player,asked,app.moves(:,2,app.numY),app.moves(:,3,app.numY),app.moves(:,4,app.numY),...
                    app.reply(app.moves(:,end,app.numY))); %Use app.num3!
            else
                app.movesTable=table();
            end
            app.Moves.Data=[];
            %Prepare 'Pick a Player' dropdown in case...
            a=1:app.numP-1;a=a+(a>=app.numY);
            app.AskPlayer.Items="👤"+a';
            app.AskPlayer.ItemsData=a;
            %Prepare 'Your Reply' dropdown in case...
            app.ReplyDD.Items=["<font color=#FF000>None</font>","Reply➡️","<font color="+app.catColorHex(1)+">Cat.1</font>",...
                "<font color="+app.catColorHex(2)+">Cat.2</font>","<font color="+app.catColorHex(3)+">Cat.3</font>"]; %Use app.num3!
            app.ReplyDD.ItemsData=-1:app.num3;
            addStyle(app.ReplyDD,uistyle("Interpreter","html"));
        end

        function moveGenerator(app)
            numMoves=height(app.Moves.Data);
            % This player's turn now:
            curply=mod(numMoves,app.numP)+1;
            % Choose whom to ask:
            if curply~=app.numY || ~app.LoadGame.Value %Easy level
                a=randi(app.numP-1,1);
                app.moves(numMoves+1,1,:)=a+(a>=curply);
            else %Only for non-easy levels
                app.NextMove.Visible="off";
                app.AskPlayer.Visible="on";
                %This surfaces the player & cards spinner for "You" to choose!
            end
            % Ask for the cards now:
            if curply~=app.numY || ~app.LoadGame.Value %Easy level
                for c=1:app.num3
                    a=randi(app.numC-length(app.commonCards{c}),1);
                    app.moves(end,c+1,:)=a+sum(a>=app.commonCards{c});
                end
            else %Only for non-easy levels
                waitfor(app.AskButton,"UserData");
                app.moves(numMoves+1,1,:)=app.AskPlayer.Value;
                app.moves(end,2:end-1,:)=repmat([app.AskC1.Value,app.AskC2.Value,app.AskC3.Value],1,1,app.numP); %Use appp.num3!
                app.NextMove.Visible="on";
                app.AskPlayer.Visible="off";
                app.AskButton.UserData=[];
            end
            % Get the reply:
            app.moves(end,app.num3+2,:)=-1; %The asked player has none of the cards by default but update below if needed! :)
            if app.moves(end,1,curply)~=app.numY || ~app.LoadGame.Value %Easy level
                for c=randperm(app.num3)
                    if ismember(app.moves(end,c+1,curply),app.plyrCards{app.moves(end,1,curply),c})
                        app.moves(end,end,:)=0;
                        app.moves(end,end,curply)=c;
                        break
                    end
                end
            else %Only for non-easy levels
                %This surfaces the player and cards for "You" to reply!
                app.NextMove.Visible="off";
                app.AskPlayer.Visible="on";
                app.AskButton.Visible="off";
                app.AskPlayer.Value=curply;
                app.AskPlayer.Enable="off";
                app.AskC1.Value=app.moves(end,1+1,curply);
                app.AskC1.Enable="off";
                app.AskC2.Value=app.moves(end,2+1,curply);
                app.AskC2.Enable="off";
                app.AskC3.Value=app.moves(end,3+1,curply);
                app.AskC3.Enable="off";
                app.ReplyDD.Visible="on";
                waitfor(app.ReplyDD,"Value");
                c=app.ReplyDD.Value;
                if c<=0 %None
                    for c=1:app.num3
                        if ismember(app.moves(end,c+1,curply),app.plyrCards{app.moves(end,1,curply),c})
                            %Wrong choice: You have a card!
                            app.moves(end,end,:)=0;
                            app.moves(end,end,curply)=c;
                            break
                        end
                    end
                else
                    if ismember(app.moves(end,c+1,curply),app.plyrCards{app.moves(end,1,curply),c})
                        app.moves(end,end,:)=0;
                        app.moves(end,end,curply)=c;
                    else
                        %Wrong choice: You don't have this card!
                        for c=1:app.num3
                            if ismember(app.moves(end,c+1,curply),app.plyrCards{app.moves(end,1,curply),c})
                                app.moves(end,end,:)=0;
                                app.moves(end,end,curply)=c;
                                break
                            end
                        end
                    end
                end
                app.NextMove.Visible="on";
                app.AskPlayer.Visible="off";
                app.AskButton.Visible="on";
                app.AskPlayer.Enable="on";
                app.AskC1.Enable="on";
                app.AskC2.Enable="on";
                app.AskC3.Enable="on";
                app.ReplyDD.Visible="off";
                app.ReplyDD.Value=0;
            end
            % Save all this move's info in the moves table (from all players' perspective?):
            app.movesTable(end+1,:)=table("👤"+curply,"👤"+app.moves(end,1,curply),app.moves(end,2,app.numY),app.moves(end,3,app.numY),...
                app.moves(end,4,app.numY),app.reply(app.moves(end,end,app.numY))); %Use app.num3!
        end

        function applyCellStyles(app,endRow)
            %"You" is highlighted in the first two columns of the moves uitable
            col=find(contains(app.Moves.Data(end,1:2),string(app.numY)));
            if ~isempty(col)
                addStyle(app.Moves,app.plyStyle,"cell",[endRow,col]);
            end
        end

        function [knowMat, sol] = inferCards(app, plyr)
            %The amazing work of Stefan Abendroth, WANG Zi-Xiang, JKMSMKJ, Vasilis Bellos.
            %Made a bit "ugly" to fit the purposes of this "app".
            plyCards=numel(cell2mat(app.yourCards));
            movesD=app.moves(1:height(app.Moves.Data),:,plyr);
            ind=movesD(:,1)+app.numP*((0:(app.num3-1))*app.numC+movesD(:,2:(app.num3+1))-1);
            movesDend=movesD(:,end);
            knowMat=double(cumsum(ones(app.numP,app.numC,app.num3))~=app.numY);
            knowMat(ind(movesDend<0,:))=0;
            for cat=1:app.num3
                knowMat(app.numY,[app.yourCards{cat},app.commonCards{cat}],cat)=2;
            end
            for cat=1:2*app.num3
                maxKnowMat=max(knowMat);
                knowMat=knowMat.*2.^(sum(knowMat>0,2:3)==plyCards).*2.^(sum(knowMat>0,1:2)==app.numC-1).*...
                    (knowMat==maxKnowMat & (sum(knowMat>1,2:3)==plyCards)<knowMat & (sum(maxKnowMat>1)==app.numC-1)<knowMat);
                knowMat(ind(1:app.num3==movesDend | ~movesDend & sum(knowMat(ind),2)==1 & knowMat(ind)))=2;
            end
            [~,sol]=min(maxKnowMat);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Setting up the GUI
            h=uiprogressdlg(app.clFigure,'Title','Setting up GUI...','Message','Please wait a moment...','Indeterminate','on');
            movegui(app.clFigure,'center');drawnow
            load("CluelessGames.mat","CG");
            app.LoadGame.Items=["Select a Game:",[CG(:).title]];
            app.LoadGame.ItemsData=-1:length(CG)-1;
            app.games=CG;
            app.levels=["I don't think. No way!";"I will think! Why not?";"I think winning is overrated..."];
            app.plyrCards=cell(app.numP,app.num3);
            app.yourCards=cell(1,app.num3);
            app.commonCards=cell(1,app.num3);
            app.moves=zeros(0,0,app.numP);
            app.movesTable=table();
            app.reply=dictionary(-1,"<font color=#FF000>NO</font>",0,"<font color=#2EBFF0>Y</font><font color=#3BAB33>E</font>"+...
                "<font color=#D1058C>S</font>",1,"<font color="+app.catColorHex(1)+">Cat.1</font>",...
                2,"<font color="+app.catColorHex(2)+">Cat.2</font>",3,"<font color="+app.catColorHex(3)+">Cat.3</font>"); %Use app.num3!
            app.solV=[];
            NumPlayersValueChanged(app,[]);
            addStyle(app.Moves,uistyle("Interpreter","html"));
            addStyle(app.Moves,uistyle("HorizontalAlignment","center"),"column",1:6);
            addStyle(app.Moves,app.catStyle(app.catColor(1,:)),"column",3);
            addStyle(app.Moves,app.catStyle(app.catColor(2,:)),"column",4);
            addStyle(app.Moves,app.catStyle(app.catColor(3,:)),"column",5);
            app.AskButton.UserData=[];
            %% Assigning callbacks via try-catch
            allGUIobjs=findobj(app.clFigure);
            for g=1:length(allGUIobjs)
                propg=properties(allGUIobjs(g));
                propg=propg(contains(propg,["Fcn","Callback"])); %Tables have CellEditCallback!
                for c=propg'
                    cllbck=allGUIobjs(g).(c{1});
                    if ~isempty(cllbck)
                        allGUIobjs(g).(c{1})=@(s,e)trychk(app,e,cllbck);
                    end
                end
            end
            %% Loading/Saving startup folder & settings...
            % app.clmat=fullfile(getenv('appdata'),'Clueless','application','cl.mat');
            % if exist(app.clmat,'file')==2
            %     load(app.clmat,'SuF','Opts');
            %     app.curpath=SuF;
            %     if ~isMATLABReleaseOlderThan("R2025a")
            %         app.AppTheme.Enable="on";
            %         if isfield(Opts,'appTheme') && app.AppTheme.State~=Opts.appTheme
            %             app.AppTheme.State=Opts.appTheme;
            %             AppTheme_Callback(app,[]);
            %         else
            %             Opts.appTheme=app.AppTheme.State;
            %             save(app.clmat,'Opts','-append');
            %         end
            %     end
            % else
            %     SuF=app.curpath;
            %     Opts.appTheme=app.AppTheme.State;
            %     try
            %         if exist(fullfile(getenv('appdata'),'Clueless','application'),'dir')~=7
            %             mkdir(fullfile(getenv('appdata'),'Clueless'),'application');
            %         end
            %         save(app.clmat,'SuF','Opts');
            %     catch error
            %         uialert(app.clFigure,error.message,'No Write-Access!😯','Icon','warning');
            %     end
            % end
            %% Finishing up...
            h.Message='GUI set up.';h.Icon='Success';drawnow
            try
                NET.addAssembly('System.Speech');
                app.speaker=System.Speech.Synthesis.SpeechSynthesizer;
                SpeakAsync(app.speaker,'Lord Ned in the Game Room! Let''s Play Clueless!');
            catch me
                getReport(me,'extended');
            end
            close(h);
            focus(app.LoadGame);
        end

        % Close request function: clFigure
        function closeupFcn(app, event)
            if ~isempty(app.speaker)
                SpeakAsync(app.speaker,'Exit, Really?');
            end
            BName=uiconfirm(app.clFigure,'Do you want to exit this Clueless game?','Exit?','Options',{'YES','NO'},'DefaultOption',2,'CancelOption',2);
            switch BName
                case 'YES'
                    if ~isempty(app.speaker)
                        SpeakAsync(app.speaker,'Lord Ned is NOT pleased!');
                    end
            %         if exist(app.clmat,'file')==2
            %             SuF=app.curpath;
            %             save(app.clmat,'SuF','-append');
            %             if app.AppTheme.Enable
            %                 load(app.clmat,'Opts');
            %                 if Opts.appTheme~=app.AppTheme.State
            %                     Opts.appTheme=~Opts.appTheme;
            %                     save(app.clmat,'Opts','-append');
            %                 end
            %             end
            %         end
                    closereq
                case 'NO'
                    if ~isempty(app.speaker)
                        SpeakAsync(app.speaker,'Lord Ned is pleased!');
                    end
            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if app.ModeSwitch.ValueIndex==1 %Play Mode
                app.LoadGame.Items=["Select a Level:";app.levels];
                app.LoadGame.ItemsData=-1:length(app.levels)-1;
                app.LoadGame.Tooltip="Select a Level";
                app.NumCards.Enable="on";
                app.NumPlayers.Enable="on";
                app.NumYou.Enable="on";
            else %Solve Mode
                app.LoadGame.Items=["Select a Game:",[app.games(:).title]];
                app.LoadGame.ItemsData=-1:length(app.games)-1;
                app.LoadGame.Tooltip="Select a Game";
                app.NumCards.Enable="off";
                app.NumPlayers.Enable="off";
                app.NumYou.Enable="off";
            end
            app.plyrCards=cell(app.numP,app.num3);
            app.yourCards=cell(1,app.num3);
            app.commonCards=cell(1,app.num3);
            app.moves=zeros(0,0,app.numP);
            app.movesTable=table();
            app.solV=[];
            focus(app.LoadGame);
        end

        % Value changed function: NumCards
        function NumCardsValueChanged(app, event)
            %NumCards sets the upper limit of Envelope & Ask spinners
            app.EnvelopeC1.Limits(2)=app.numC;
            app.EnvelopeC2.Limits(2)=app.numC;
            app.EnvelopeC3.Limits(2)=app.numC;
            app.AskC1.Limits(2)=app.numC;
            app.AskC2.Limits(2)=app.numC;
            app.AskC3.Limits(2)=app.numC;
        end

        % Value changed function: NumPlayers
        function NumPlayersValueChanged(app, event)
            %Add these many players and their card boxes... (Make this more efficient and compact!)
            app.Check.Layout.Row=app.numP+3;
            app.Gcards.RowHeight=repelem({'1x',45},[app.numP+2 1]);
            app.EnvelopeCsLabel.Layout.Row=app.numP+2;
            app.EnvelopeC1.Layout.Row=app.numP+2;
            app.EnvelopeC2.Layout.Row=app.numP+2;
            app.EnvelopeC3.Layout.Row=app.numP+2;
            app.CommonCsLabel.Layout.Row=app.numP+1;
            app.CommonC1.Layout.Row=app.numP+1;
            app.CommonC2.Layout.Row=app.numP+1;
            app.CommonC3.Layout.Row=app.numP+1;
            if ~isscalar(app.PlayersCsLabel)
                delete(app.PlayersCsLabel(2:end));
                delete(app.PlayersC1(2:end));
                delete(app.PlayersC2(2:end));
                delete(app.PlayersC3(2:end));
                app.PlayersCsLabel(app.numP+1:end)=[];
                app.PlayersC1(app.numP+1:end)=[];
                app.PlayersC2(app.numP+1:end)=[];
                app.PlayersC3(app.numP+1:end)=[];
            end
            for n=2:app.numP
                app.PlayersCsLabel(n)=copyobj(app.PlayersCsLabel(1),app.Gcards);
                app.PlayersCsLabel(n).Layout.Row=n;
                app.PlayersCsLabel(n).Text=replace(app.PlayersCsLabel(1).Text,"1",num2str(n));
                app.PlayersC1(n)=copyobj(app.PlayersC1(1),app.Gcards);
                app.PlayersC1(n).Layout.Row=n;
                app.PlayersC2(n)=copyobj(app.PlayersC2(1),app.Gcards);
                app.PlayersC2(n).Layout.Row=n;
                app.PlayersC3(n)=copyobj(app.PlayersC3(1),app.Gcards);
                app.PlayersC3(n).Layout.Row=n;
            end
            NumCardsValueChanged(app,[]);
            NumYouValueChanged(app,[]);
        end

        % Value changed function: NumYou
        function NumYouValueChanged(app, event)
            %Make sure only "you" look like "you"
            set(app.PlayersCsLabel(:),BackgroundColor='none');
            app.PlayersCsLabel(app.numY).BackgroundColor=app.plyColor;
            %Reset the card boxes
            gameLoader(app);
        end

        % Button pushed function: LetsPlay
        function LetsPlayButtonPushed(app, event)
            if app.LoadGame.Value==-1
                return
            end
            if app.ModeSwitch.ValueIndex==2 %Solve Mode: Just load a game and wait for "Next Move"
                gID=1+app.LoadGame.Value;
                app.NumPlayers.Value=app.games(gID).m;app.numP; %Necessary for NumYou assignment!
                app.NumCards.Value=app.games(gID).n;
                app.NumYou.Value=app.games(gID).pnum;
                app.yourCards=app.games(gID).yourcards;
                app.commonCards=app.games(gID).commoncards;
                app.moves(1:height(app.games(gID).moves),1:width(app.games(gID).moves),app.numY)=app.games(gID).moves;
                app.solV=app.games(gID).sol;
                %NumCardsValueChanged(app,[]);
                NumPlayersValueChanged(app,[]);
            else %Play Mode: Just generate a game and wait for "Next Move"
                app.plyrCards=cell(app.numP,app.num3);
                app.yourCards=cell(1,app.num3);
                app.commonCards=cell(1,app.num3);
                app.moves=zeros(0,0,app.numP);
                app.solV=[];
                gameGenerator(app);
            end
            if height(app.Moves.StyleConfigurations)>5 %Keep the 5 basic styles applied to the table in startup!
                removeStyle(app.Moves,6:height(app.Moves.StyleConfigurations));
            end
            %(De)Activate Panels
            app.GameSetupPanel.Enable="off";
            app.CardsDistPanel.Enable="on";
            app.MovesListPanel.Enable="on";
        end

        % Button pushed function: NextMove
        function NextMoveButtonPushed(app, event)
            if app.ModeSwitch.ValueIndex==1 %Play Mode
                %Generate a move
                moveGenerator(app);
                app.Moves.Data=[app.Moves.Data;app.movesTable{end,:}];
                applyCellStyles(app,height(app.Moves.Data));
                numMoves=height(app.Moves.Data);
            else %Solve Mode
                %Populate the table with moves one-by-one
                numMoves=height(app.Moves.Data);
                if numMoves==height(app.moves)
                    uialert(app.clFigure,"That's all the moves! Guess the envelope's contents now.","Time for a solution!🤔","Icon","info");
                else
                    app.Moves.Data=[app.Moves.Data;app.movesTable{numMoves+1,:}];
                    applyCellStyles(app,numMoves+1);
                end
            end
            scroll(app.Moves,"bottom");
            %Fill in the inferences till this point!
            kmat=inferCards(app,app.numY);
            for m=setdiff(1:app.numP,app.numY)
                app.PlayersC1(m).Value=erase(strcat("{"+strjoin(string(find(squeeze(kmat(m,:,1))==1)),",")+"}",...
                    "["+strjoin(string(find(squeeze(kmat(m,:,1))>1)),",")+"]"),"{}");
                app.PlayersC2(m).Value=erase(strcat("{"+strjoin(string(find(squeeze(kmat(m,:,2))==1)),",")+"}",...
                    "["+strjoin(string(find(squeeze(kmat(m,:,2))>1)),",")+"]"),"{}");
                app.PlayersC3(m).Value=erase(strcat("{"+strjoin(string(find(squeeze(kmat(m,:,3))==1)),",")+"}",...
                    "["+strjoin(string(find(squeeze(kmat(m,:,3))>1)),",")+"]"),"{}");
            end
            drawnow
            %Check if the current player have figured it out!
            if app.LoadGame.Value==length(app.levels)-1 %Max level
                curply=mod(numMoves-1,app.numP)+1;
                if curply~=app.numY
                    [~,sol2chk]=inferCards(app,curply);
                    if isequal(app.solV(:)',sol2chk(:)') %Check the envelope
                        app.EnvelopeC1.Value=app.solV(1);
                        app.EnvelopeC2.Value=app.solV(2);
                        app.EnvelopeC3.Value=app.solV(3);
                        uialert(app.clFigure,["Clueless No More! Congratulations!🎉";"👤"+curply+" Did It!🥳"],"Game Over!👏","Icon","success");
                    end
                end
                %End the game?
            end
        end

        % Button pushed function: AskButton
        function AskButtonButtonPushed(app, event)
            app.AskButton.UserData=1; %Returns to moveGenerator!
        end

        % Button pushed function: Check
        function CheckButtonPushed(app, event)
            winner="You";
            if app.ModeSwitch.ValueIndex==1 %Play Mode
                if app.LoadGame.Value==length(app.levels)-1 %Max level
                    if mod(height(app.Moves.Data)-1,app.numP)+1==app.numY
                        winner="👤"+app.NumYou.Value;
                    else
                        uialert(app.clFigure,"Wait for your turn!🤫","Clueless As Ever!😔","Icon","warning");
                        return
                    end
                end
            end
            if isequal(app.solV(:)',app.solE) %Check the envelope
                uialert(app.clFigure,["Clueless No More! Congratulations!🎉";winner+" Did It!🥳"],"Game Over!👏","Icon","success");
                %(De)Activate Panels
                app.GameSetupPanel.Enable="on";
                app.CardsDistPanel.Enable="off";
                app.MovesListPanel.Enable="off";
                %Do not reset the game data now! Leave it for save functionality...
            else %Keep trying
                uialert(app.clFigure,"Sorry! Keep Trying...","Clueless As Ever!😔","Icon","info");
            end
        end

        % Callback function: LightDark
        function LightDarkClicked(app, event)
            if app.LightDark.State %Dark
                app.clFigure.Theme.BaseColorStyle="dark";
                app.LightDark.Tooltip="Switch to Light Mode";
            else %Light
                app.clFigure.Theme.BaseColorStyle="light";
                app.LightDark.Tooltip="Switch to Dark Mode";
            end
            app.Moves.BackgroundColor=fliplightness(app.Moves.BackgroundColor);
        end

        % Callback function: About
        function AboutClicked(app, event)
            if ~isempty(app.speaker)
                str=['Many thanks to Matt Tearle and Chen Lin, the organizers of Cody Contest 2025. ',...
                    'Special thanks to my fellow Cody participants: Stefan Abendroth, WANG Zi-Xiang and Vasilis Bellos.'];
                if strcmpi(char(ToString(app.speaker.State)),'Ready')
                    SpeakAsync(app.speaker,str);
                end
            end
            abt=["Clueless";"Lord Ned in the Game Room";"<a href=""https://www.mathworks.com/matlabcentral/cody/groups/96682/problems/61069"">"+...
                "Problem 61069 of Cody Contest 2025</a>";" ";"Version " + app.majVer + "." + app.minVer + ".χ; Release " + erase(app.relz," ");...
                string(app.relzDate,'MMMM dd, yyyy');" ";"Cody Contest 2025: Matt Tearle and Chen Lin.";" ";...
                "Algorithm: ⬇️, S. Abendroth, Z.-X. Wang, and V. Bellos.";"©️ 2026 JKMSMKJ"];
            uialert(app.clFigure,abt,'About','Icon','info','Interpreter','html');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.clFigure = uifigure('Visible', 'off');
            app.clFigure.Name = 'Clueless Game';
            app.clFigure.CloseRequestFcn = createCallbackFcn(app, @closeupFcn, true);
            app.Toolbar = uitoolbar(app.clFigure);
            app.LightDark = uitoggletool(app.Toolbar);
            app.LightDark.Tooltip = {'Switch to Dark Mode'};
            app.LightDark.ClickedCallback = createCallbackFcn(app, @LightDarkClicked, true);
            app.LightDark.Icon = fullfile(pathToMLAPP, 'Clueless_resources', 'theme_tb_image.png');
            app.About = uipushtool(app.Toolbar);
            app.About.Tooltip = {'About'};
            app.About.ClickedCallback = createCallbackFcn(app, @AboutClicked, true);
            app.About.Icon = fullfile(pathToMLAPP, 'Clueless_resources', 'abt_tb_image.png');
            app.About.Separator = 'on';
            app.GclBoard = uigridlayout(app.clFigure);
            app.clBoard = uipanel(app.GclBoard);
            app.GclLayout = uigridlayout(app.clBoard);
            app.GameSetupPanel = uipanel(app.GclLayout);
            app.GameSetupPanel.Title = '💂Game Setup';
            app.Gsetup = uigridlayout(app.GameSetupPanel);
            app.NumCatsLabel = uilabel(app.Gsetup);
            app.NumCatsLabel.Text = 'Number of Categories:';
            app.LoadGame = uidropdown(app.Gsetup);
            app.LoadGame.Items = {};
            app.LoadGame.Tooltip = {'Select a Game'};
            app.LoadGame.Value = {};
            app.NumCats = uispinner(app.Gsetup);
            app.NumCats.Limits = [3 5];
            app.NumCats.RoundFractionalValues = 'on';
            app.NumCats.ValueDisplayFormat = '%.0f';
            app.NumCats.Editable = 'off';
            app.NumCats.Enable = 'off';
            app.NumCats.Value = 3;
            app.NumCardsLabel = uilabel(app.Gsetup);
            app.NumCardsLabel.Text = 'Number of Cards:';
            app.NumCards = uispinner(app.Gsetup);
            app.NumCards.Limits = [4 8];
            app.NumCards.RoundFractionalValues = 'on';
            app.NumCards.ValueDisplayFormat = '%.0f';
            app.NumCards.ValueChangedFcn = createCallbackFcn(app, @NumCardsValueChanged, true);
            app.NumCards.Enable = 'off';
            app.NumCards.Value = 4;
            app.NumPlayersLabel = uilabel(app.Gsetup);
            app.NumPlayersLabel.Text = 'Number of Players:';
            app.NumPlayers = uispinner(app.Gsetup);
            app.NumPlayers.Limits = [4 8];
            app.NumPlayers.RoundFractionalValues = 'on';
            app.NumPlayers.ValueDisplayFormat = '%.0f';
            app.NumPlayers.ValueChangedFcn = createCallbackFcn(app, @NumPlayersValueChanged, true);
            app.NumPlayers.Enable = 'off';
            app.NumPlayers.Value = 4;
            app.NumYouLabel = uilabel(app.Gsetup);
            app.NumYouLabel.Text = 'You are Player #:';
            app.NumYou = uispinner(app.Gsetup);
            app.NumYou.Limits = [1 8];
            app.NumYou.RoundFractionalValues = 'on';
            app.NumYou.ValueDisplayFormat = '%.0f';
            app.NumYou.ValueChangedFcn = createCallbackFcn(app, @NumYouValueChanged, true);
            app.NumYou.Enable = 'off';
            app.NumYou.Value = 4;
            app.LetsPlay = uibutton(app.Gsetup, 'push');
            app.LetsPlay.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlay.Text = {'👑Lord Ned Commands:'; 'Let''s Begin the Game!🤔'};
            app.ModeSwitch = uiswitch(app.Gsetup, 'slider');
            app.ModeSwitch.Items = {'Play Mode', 'Solve Mode'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Tooltip = {'Choose a Mode'};
            app.ModeSwitch.Value = 'Solve Mode';
            app.CardsDistPanel = uipanel(app.GclLayout);
            app.CardsDistPanel.Enable = 'off';
            app.CardsDistPanel.Title = '🎴Cards Distribution';
            app.Gcards = uigridlayout(app.CardsDistPanel);
            app.PlayersCsLabel = uilabel(app.Gcards);
            app.PlayersCsLabel.Text = '👤1';
            app.PlayersC1 = uieditfield(app.Gcards, 'text');
            app.PlayersC1.Editable = 'off';
            app.PlayersC1.Value = '{?}';
            app.PlayersC2 = uieditfield(app.Gcards, 'text');
            app.PlayersC2.Editable = 'off';
            app.PlayersC2.Value = '{?}';
            app.PlayersC3 = uieditfield(app.Gcards, 'text');
            app.PlayersC3.Editable = 'off';
            app.PlayersC3.Value = '{?}';
            app.CommonCsLabel = uilabel(app.Gcards);
            app.CommonCsLabel.Text = '🃏';
            app.CommonC1 = uieditfield(app.Gcards, 'text');
            app.CommonC1.Editable = 'off';
            app.CommonC1.Value = '{?}';
            app.CommonC2 = uieditfield(app.Gcards, 'text');
            app.CommonC2.Editable = 'off';
            app.CommonC2.Value = '{?}';
            app.CommonC3 = uieditfield(app.Gcards, 'text');
            app.CommonC3.Editable = 'off';
            app.CommonC3.Value = '{?}';
            app.EnvelopeCsLabel = uilabel(app.Gcards);
            app.EnvelopeCsLabel.Text = '✉️';
            app.EnvelopeC1 = uispinner(app.Gcards);
            app.EnvelopeC1.Limits = [0 4];
            app.EnvelopeC1.RoundFractionalValues = 'on';
            app.EnvelopeC1.ValueDisplayFormat = '%.0f';
            app.EnvelopeC2 = uispinner(app.Gcards);
            app.EnvelopeC2.Limits = [0 4];
            app.EnvelopeC2.RoundFractionalValues = 'on';
            app.EnvelopeC2.ValueDisplayFormat = '%.0f';
            app.EnvelopeC3 = uispinner(app.Gcards);
            app.EnvelopeC3.Limits = [0 4];
            app.EnvelopeC3.RoundFractionalValues = 'on';
            app.EnvelopeC3.ValueDisplayFormat = '%.0f';
            app.Check = uibutton(app.Gcards, 'push');
            app.Check.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.Check.Text = '☝️Check Your Solution☝️';
            app.MovesListPanel = uipanel(app.GclLayout);
            app.MovesListPanel.Enable = 'off';
            app.MovesListPanel.Title = '📋Moves List';
            app.Gmoves = uigridlayout(app.MovesListPanel);
            app.Moves = uitable(app.Gmoves);
            app.Moves.ColumnName = {'Player'; 'Asked'; 'Cat.1'; 'Cat.2'; 'Cat.3'; 'Reply'};
            app.Moves.SelectionType = 'row';
            app.AskPlayer = uidropdown(app.Gmoves);
            app.AskPlayer.Items = {};
            app.AskPlayer.Editable = 'on';
            app.AskPlayer.Visible = 'off';
            app.AskPlayer.Tooltip = {'Pick a Player'};
            app.AskPlayer.Value = {};
            app.AskC1 = uispinner(app.Gmoves);
            app.AskC1.Limits = [1 4];
            app.AskC1.RoundFractionalValues = 'on';
            app.AskC1.ValueDisplayFormat = '%.0f';
            app.AskC1.Value = 1;
            app.AskC2 = uispinner(app.Gmoves);
            app.AskC2.Limits = [1 4];
            app.AskC2.RoundFractionalValues = 'on';
            app.AskC2.ValueDisplayFormat = '%.0f';
            app.AskC2.Value = 1;
            app.AskC3 = uispinner(app.Gmoves);
            app.AskC3.Limits = [1 4];
            app.AskC3.RoundFractionalValues = 'on';
            app.AskC3.ValueDisplayFormat = '%.0f';
            app.AskC3.Value = 1;
            app.ReplyDD = uidropdown(app.Gmoves);
            app.ReplyDD.Items = {'None', 'Reply➡️', 'Cat.1', 'Cat.2', 'Cat.3'};
            app.ReplyDD.ItemsData = [-1 0 1 2 3];
            app.ReplyDD.Visible = 'off';
            app.ReplyDD.Tooltip = {'Your Reply'};
            app.ReplyDD.Value = 0;
            app.AskButton = uibutton(app.Gmoves, 'push');
            app.AskButton.ButtonPushedFcn = createCallbackFcn(app, @AskButtonButtonPushed, true);
            app.AskButton.Text = '➡️Ask';
            app.NextMove = uibutton(app.Gmoves, 'push');
            app.NextMove.ButtonPushedFcn = createCallbackFcn(app, @NextMoveButtonPushed, true);
            app.NextMove.Text = '➡️Next Move➡️';
            % Show the figure after all components are created
            app.clFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clueless

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.clFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.clFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.clFigure)
        end
    end
end